classdef imageSearchApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FindSimilarLabel                matlab.ui.control.Label
        EnterPictureFileEditFieldLabel  matlab.ui.control.Label
        EnterPictureFileEditField       matlab.ui.control.EditField
        DisplayButton                   matlab.ui.control.Button
        FeatureExtractionDropDownLabel  matlab.ui.control.Label
        FeatureExtractionDropDown       matlab.ui.control.DropDown
        KNNComparisonDropDownLabel      matlab.ui.control.Label
        KNNComparisonDropDown           matlab.ui.control.DropDown
        ListBox                         matlab.ui.control.ListBox
        ShowResultButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: DisplayButton
        function search(app, event)
            feature = 0;
            knn = 0;
            if app.FeatureExtractionDropDown.Value == "1"
                feature = 1;
            elseif app.FeatureExtractionDropDown.Value == "2"
                feature = 2;
            elseif app.FeatureExtractionDropDown.Value == "3"
                feature = 3;
            elseif app.FeatureExtractionDropDown.Value == "4"
                feature = 4;
            end
            
            if app.KNNComparisonDropDown.Value == "1"
                knn = 1;
            elseif app.KNNComparisonDropDown.Value == "2"
                knn = 2;
            end
            

            app.ListBox.Items = part1(app.EnterPictureFileEditField.Value, feature, knn);
            
        end

        % Button pushed function: ShowResultButton
        function show(app, event)
            showImage(app.ListBox.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FindSimilarLabel = uilabel(app.UIFigure);
            app.FindSimilarLabel.Text = 'Find Similar';
            app.EnterPictureFileEditFieldLabel = uilabel(app.UIFigure);
            app.EnterPictureFileEditFieldLabel.Text = 'Enter Picture File';
            app.EnterPictureFileEditField = uieditfield(app.UIFigure, 'text');
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @search, true);
            app.DisplayButton.Text = 'Display';
            app.FeatureExtractionDropDownLabel = uilabel(app.UIFigure);
            app.FeatureExtractionDropDownLabel.Text = 'Feature Extraction';
            app.FeatureExtractionDropDown = uidropdown(app.UIFigure);
            app.FeatureExtractionDropDown.Items = {'Average Pixel Color', 'Spatial Grid of Average Pixel Colors', 'Color Histogram', 'Average Edge Energy in Spatial Grid'};
            app.FeatureExtractionDropDown.ItemsData = {'1', '2', '3', '4'};
            app.FeatureExtractionDropDown.Value = '1';
            app.KNNComparisonDropDownLabel = uilabel(app.UIFigure);
            app.KNNComparisonDropDownLabel.Text = 'KNN Comparison';
            app.KNNComparisonDropDown = uidropdown(app.UIFigure);
            app.KNNComparisonDropDown.Items = {'Sum of Square Differences', 'Angle Between Vectors'};
            app.KNNComparisonDropDown.ItemsData = {'1', '2'};
            app.KNNComparisonDropDown.Value = '1';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'', '', '', '', '', '', '', '', '', ''};
            app.ListBox.Value = '';
            app.ShowResultButton = uibutton(app.UIFigure, 'push');
            app.ShowResultButton.ButtonPushedFcn = createCallbackFcn(app, @show, true);
            app.ShowResultButton.Text = 'Show Result';
        end
    end

    methods (Access = public)

        % Construct app
        function app = imageSearchApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end